classdef EELS3D_TOOLBOX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        ImportMenu                    matlab.ui.container.Menu
        ClearMenu                     matlab.ui.container.Menu
        SaveasMenu                    matlab.ui.container.Menu
        ImportfromworkspaceMenu       matlab.ui.container.Menu
        ExporttoworkspaceMenu         matlab.ui.container.Menu
        InfoMenu                      matlab.ui.container.Menu
        DatasizeMenu                  matlab.ui.container.Menu
        PixelsizeMenu                 matlab.ui.container.Menu
        SpectralsamplingMenu          matlab.ui.container.Menu
        FilepathMenu                  matlab.ui.container.Menu
        ProcessMenu                   matlab.ui.container.Menu
        RemoveoutliersMenu            matlab.ui.container.Menu
        CropMenu                      matlab.ui.container.Menu
        DisplayMenu                   matlab.ui.container.Menu
        SetenergylimitMenu            matlab.ui.container.Menu
        LogscaleMenu                  matlab.ui.container.Menu
        OptionsMenu                   matlab.ui.container.Menu
        SavecurrentsettingsMenu       matlab.ui.container.Menu
        RestoredefaultparametersMenu  matlab.ui.container.Menu
        CustomMenu                    matlab.ui.container.Menu
        ProcessingfunctionsMenu       matlab.ui.container.Menu
        VisualizingfunctionsMenu      matlab.ui.container.Menu
        ExecutableMenu                matlab.ui.container.Menu
        RefreshMenu                   matlab.ui.container.Menu
        ImportButton                  matlab.ui.control.Button
        DatahistoryDropDownLabel      matlab.ui.control.Label
        DatahistoryDropDown           matlab.ui.control.DropDown
        DevelopedbyRuishiQiLabel      matlab.ui.control.Label
        VisualizeyourdataPanel        matlab.ui.container.Panel
        LineprofileButton             matlab.ui.control.Button
        VisualizerDropDownLabel       matlab.ui.control.Label
        VisualizerDropDown            matlab.ui.control.DropDown
        SumalongDropDownLabel         matlab.ui.control.Label
        SumalongDropDown              matlab.ui.control.DropDown
        meVLabel_6                    matlab.ui.control.Label
        MapsignalButton               matlab.ui.control.Button
        WidthEditFieldLabel           matlab.ui.control.Label
        WidthEditField                matlab.ui.control.NumericEditField
        EnergyEditFieldLabel          matlab.ui.control.Label
        EnergyEditField               matlab.ui.control.EditField
        Label                         matlab.ui.control.Label
        SumRegionEditField            matlab.ui.control.EditField
        ProcessyourdataPanel          matlab.ui.container.Panel
        DeconvolutionButton           matlab.ui.control.Button
        IterationSpinnerLabel         matlab.ui.control.Label
        IterationSpinner              matlab.ui.control.Spinner
        SubtractBackgroundButton      matlab.ui.control.Button
        FromEditField1                matlab.ui.control.NumericEditField
        meVLabel_7                    matlab.ui.control.Label
        FromEditField2                matlab.ui.control.NumericEditField
        toEditFieldLabel              matlab.ui.control.Label
        toEditField2                  matlab.ui.control.NumericEditField
        toEditField_2Label            matlab.ui.control.Label
        toEditField1                  matlab.ui.control.NumericEditField
        FittypeDropDownLabel          matlab.ui.control.Label
        FittypeDropDown               matlab.ui.control.DropDown
        BM3DdenoiseButton             matlab.ui.control.Button
        meVLabel_9                    matlab.ui.control.Label
        toEditField_3Label            matlab.ui.control.Label
        BM3DtoEditField               matlab.ui.control.NumericEditField
        FromEditField_3Label          matlab.ui.control.Label
        BM3DFromEditField             matlab.ui.control.NumericEditField
        NormalizeButton               matlab.ui.control.Button
        RemovebadpointsinsidethesampleCheckBox  matlab.ui.control.CheckBox
        AlignButton                   matlab.ui.control.Button
        DropDown                      matlab.ui.control.DropDown
        MethodLabel                   matlab.ui.control.Label
        SavecurrentdataButton         matlab.ui.control.Button
        andLabel                      matlab.ui.control.Label
        ManuallyadjustenergywindowsButton  matlab.ui.control.Button
        SaveallhistoryButton          matlab.ui.control.Button
        ClearButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        currentData
        filename
        toolboxpath
        elim=[];
        history=[];
        previewfig
        %         wtbar
    end
    
    methods (Access = private)
        
        function [] = setbusy(app)
            app.UIFigure.UserData=uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Just a moment','Indeterminate','on');
        end
        function [] = setready(app)
            app.updatepreview;
            close(app.UIFigure.UserData);
        end

        
        function []=updatedata(app,newdata,datatag)
            if nargin<3
                datatag='?';
            end
            newdata.info.tag=datatag;
            app.currentData=newdata;
            app.history=[app.history,newdata];
            tagarr={};
            for ii=1:numel(app.history)
                tagarr{ii}=['[',num2str(ii),'] ',app.history(ii).info.tag];
            end
            app.DatahistoryDropDown.Items=tagarr;
            app.DatahistoryDropDown.ItemsData=(num2cell((1:numel(app.history))));
            app.DatahistoryDropDown.Value=numel(app.history);
        end
        
        function []=updatepreview(app,~,~,~)
            %             if
            if isempty(app.currentData)
                set(app.previewfig,'visible','off')
            else
                set(app.previewfig,'visible','on')
                visualize3ddata(app.currentData.data,app.currentData.ene,app.previewfig)
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % system check
            addons = matlab.addons.installedAddons;
            req_addon={'Image Processing Toolbox','Curve Fitting Toolbox','Signal Processing Toolbox','Deep Learning Toolbox','Optimization Toolbox'};
            for ii=1:numel(req_addon)
                if ~sum(contains(addons.Name,req_addon{ii}))
                    uialert(app.UIFigure,[req_addon{ii},' is required'],'FATAL ERROR: Missing Matlab add-on(s)')
                    app.ImportButton.Enable=0;
                    app.ImportfromworkspaceMenu.Enable=0;
                    app.ImportMenu.Enable=0;
                end
            end
            v=version('-release');
            if str2num(v(1:4))<2019||strcmp(v,'2019a')
                uialert(app.UIFigure,['Matlab version 2019b or newer is required'],'FATAL ERROR: Please update your Matlab')
                app.ImportButton.Enable=0;
                app.ImportfromworkspaceMenu.Enable=0;
                app.ImportMenu.Enable=0;
            end
%             warning('off','all')
            app.UIFigure.Tag='3DEELSTOOLBOX';
            [directory,~,~] = fileparts(mfilename('fullpath'));
            addpath(genpath(directory))
            app.toolboxpath=directory;
            app.RefreshMenuSelected;
            try
                load([app.toolboxpath,'/3DEELS/Process/defaultconfig.mat'])
                app.DropDown.Value=alighmethod;
                app.RemovebadpointsinsidethesampleCheckBox.Value=removebadpoints;
                app.BM3DFromEditField.Value=bm3dfrom;
                app.BM3DtoEditField.Value=bm3dto;
                app.FittypeDropDown.Value=bgfun;
                app.FromEditField1.Value=bgwin1from;
                app.FromEditField2.Value=bgwin2from;
                app.toEditField1.Value=bgwin1to;
                app.toEditField2.Value=bgwin2to;
                app.IterationSpinner.Value=decinter;
                app.EnergyEditField.Value=mapene;
                app.WidthEditField.Value=mapwidth;
                app.SumalongDropDown.Value=lineprofsumalong;
                app.VisualizerDropDown.Value=lineprofvis;
                app.elim=elim;
            catch
                warning 'User-defined parameters not found. Use the default values instead'
            end
            app.previewfig=figure('Position',app.UIFigure.Position+[app.UIFigure.OuterPosition(3) -22 0 -3],'visible','off',...
                'Toolbar','none','HandleVisibility',"callback");
            set(app.previewfig,'CloseRequestFcn',@app.updatepreview);
        end

        % Callback function: ImportButton, ImportMenu
        function ImportButtonPushed(app, event)
            app.setbusy;
            [file,path] = uigetfile({'*'});
            if file==0
                app.setready
                return
            end
            if strcmp(file(end-2:end),'dm3')
                app.updatedata(DM3Import( [path,file] ),'Import dm3');
            elseif strcmp(file(end-2:end),'mat')
                S=load([path,file],'-mat');
                if isfield(S,'data')
                    app.updatedata(S.data,'Import mat');
                elseif isfield(S,'hist')
                    if ~isempty(app.currentData)
                        answer = uiconfirm(app.UIFigure,'Overwrite the current data?','Confirm overwrite',...
                            'Icon','warning');
                        if strcmp(answer,'Cancel')
                            app.setready
                            return
                        end
                    end
                    app.history=S.hist(1:end-1);
                    app.updatedata(S.hist(end),S.hist(end).info.tag)
                else
                    uialert(app.UIFigure,'Invalid mat file','Import failure')
                    return
                end
            else
                uialert(app.UIFigure,'Unsupported file type','Import failure')
                return
            end
            app.filename=[path,file];
            app.updatepreview
            app.setready;
        end

        % Button pushed function: AlignButton
        function AlignButtonPushed(app, event)
            app.setbusy;
            value = app.DropDown.Value;
            if strcmp(value,'Cross correlation')
                alignmethod='xcorr';
            elseif strcmp(value,'Center of mass')
                alignmethod='COM';
            else
                alignmethod='gaussian';
            end
            AlignedData=Align(app.currentData,alignmethod);
            if AlignedData.info.issaturated
                answer = uiconfirm(app.UIFigure,'It seems that this data is saturated. Is this correct?', ...
               	'ZLP saturated?', ...
               	'Options',{'No','Yes'},'DefaultOption',2);
                switch answer
                    case 'Yes'
                        uialert(app.UIFigure,'It is strongly recommendable to avoid saturation!','Warning');
                    case 'No'
                        AlignedData.info.issaturated=0;
                end
            end
            app.updatedata(AlignedData,['Align: ',alignmethod]);
            %                 app.addhist()
            app.setready;
        end

        % Button pushed function: NormalizeButton
        function NormalizeButtonPushed(app, event)
            app.setbusy;
            NormalizedData=Normalize(app.currentData);
            if app.RemovebadpointsinsidethesampleCheckBox.Value
                NormalizedData=Sift(NormalizedData);
            end
            app.updatedata(NormalizedData,'Normalize');
            %                 app.addhist([])
            app.setready;
        end

        % Button pushed function: SubtractBackgroundButton
        function SubtractBackgroundButtonPushed(app, event)
            app.setbusy;
            SignalData=BackgroundSubtraction(app.currentData,[app.FromEditField1.Value, app.toEditField1.Value, app.FromEditField2.Value, app.toEditField2.Value],app.FittypeDropDown.Value);
            app.updatedata(SignalData, ...
                ['BG-sub: ',app.FittypeDropDown.Value,' (',num2str(app.FromEditField1.Value),'-',num2str(app.toEditField1.Value),', ',num2str(app.FromEditField2.Value),'-',num2str(app.toEditField2.Value),')']);
            app.setready;
        end

        % Button pushed function: BM3DdenoiseButton
        function BM3DdenoiseButtonPushed(app, event)
            app.setbusy;
            DenoisedData=BM3Ddenoise(app.currentData,[app.BM3DFromEditField.Value, app.BM3DtoEditField.Value]);%
            app.updatedata(DenoisedData,...
                ['BM3D (',num2str(app.BM3DFromEditField.Value),'-',num2str(app.BM3DtoEditField.Value),')']);
            app.setready;
        end

        % Button pushed function: DeconvolutionButton
        function DeconvolutionButtonPushed(app, event)
            app.setbusy;
            DeconvolutedData=LucyRichardsonDeconv(app.currentData,[],app.IterationSpinner.Value);
            app.updatedata(DeconvolutedData,['Deconvolution (',num2str(app.IterationSpinner.Value),' iter.)'])
            app.setready;
        end

        % Button pushed function: MapsignalButton
        function MapsignalButtonPushed(app, event)
            app.setbusy;
            MapSignal(app.currentData,eval(['[',app.EnergyEditField.Value,']']),app.WidthEditField.Value);
            %             assignin('base','mapping',mapping)
            app.setready;
        end

        % Value changed function: DatahistoryDropDown
        function DatahistoryDropDownValueChanged(app, event)
            app.setbusy;
            ind = app.DatahistoryDropDown.Value;
            app.updatedata(app.history(ind),['Back to [',num2str(app.DatahistoryDropDown.Value),']'])
            app.setready;
        end

        % Button pushed function: LineprofileButton
        function LineprofileButtonPushed(app, event)
            app.setbusy;
            
            if app.SumalongDropDown.Value=='2'
                sumregion=strrep(app.SumRegionEditField.Value,'end',num2str(app.currentData.ydim));
                sumdir=1;
            else
                sumregion=strrep(app.SumRegionEditField.Value,'end',num2str(app.currentData.xdim));
                sumdir=2;
            end
            sumregion=str2num(sumregion);
            
            
            LineProf=GenerateLineprofile(app.currentData,sumdir,sumregion);
            
            
            if app.VisualizerDropDown.Value=='1'
                LineProf.imagesc
                if ~isempty(app.elim)
                    ylim(app.elim)
                end
            elseif app.VisualizerDropDown.Value=='2'
                LineProf.plot
                if ~isempty(app.elim)
                    xlim(app.elim)
                end
            else
                LineProf.imagesc
                if ~isempty(app.elim)
                    ylim(app.elim)
                end
                LineProf.plot
                if ~isempty(app.elim)
                    xlim(app.elim)
                end
            end
            app.setready;
        end

        % Menu selected function: SavecurrentsettingsMenu
        function SaveasdefaultButtonPushed(app, event)
 
            answer = uiconfirm(app.UIFigure,'Do you really wish to set your parameters as default?', ...
               	'Comfirm?', ...
               	'Options',{'Cancel','Overwrite'},'DefaultOption',2);
            if strcmp(answer,'Cancel')
                return
            end
            alighmethod=app.DropDown.Value;
            removebadpoints=app.RemovebadpointsinsidethesampleCheckBox.Value;
            bm3dfrom=app.BM3DFromEditField.Value;
            bm3dto=app.BM3DtoEditField.Value;
            bgfun=app.FittypeDropDown.Value;
            bgwin1from=app.FromEditField1.Value;
            bgwin2from=app.FromEditField2.Value;
            bgwin1to=app.toEditField1.Value;
            bgwin2to=app.toEditField2.Value;
            decinter=app.IterationSpinner.Value;
            mapene=app.EnergyEditField.Value;
            mapwidth=app.WidthEditField.Value;
            lineprofsumalong=app.SumalongDropDown.Value;
            lineprofvis=app.VisualizerDropDown.Value;
            elim=app.elim;
            save([app.toolboxpath,'/3DEELS/Process/defaultconfig.mat'],'alighmethod','removebadpoints','bm3dfrom',...
                'bm3dto','bgfun','bgwin1from','bgwin2from','bgwin1to','bgwin2to','decinter','mapene','mapwidth',...
                'lineprofsumalong','lineprofvis','elim','-mat')
        end

        % Callback function: ClearButton, ClearMenu
        function ClearButtonPushed(app, event)
%             app.setbusy;
            answer = uiconfirm(app.UIFigure,'Do you wish to clear all data? Unsaved results will be lost!', ...
               	'Comfirm?', ...
               	'Options',{'Clear','Cancel'},'DefaultOption',2);
            
            if strcmp(answer,'Cancel')
                return
            end
            app.history=[];
            app.currentData=[];
            app.DatahistoryDropDown.Items={};
            app.DatahistoryDropDown.ItemsData={};
            app.setready;
        end

        % Button pushed function: ManuallyadjustenergywindowsButton
        function ManuallyadjustenergywindowsButtonPushed(app, event)
            if isempty(app.currentData)
                uialert(app.UIFigure,'Please import a dataset!','Error')
                return
            end
            figure('NumberTitle', 'off', 'Name', 'Select BG region','position',[100 100 800 400]);
            try
                totint=app.currentData.info.totint;
            catch
                totint=sum(app.currentData.data,3);
            end
            subplot('Position',[0.02 0.15 0.3 0.8])
            imagesc(totint),axis image off
            [x,y]=find(totint==max(totint(:)));
            rct=imrect(gca,[y(1)-0.5 x(1)-0.5 1 1]);
            pos=rct.getPosition;
            pos=round(pos+[0.5 0.5 0 0]);
            pos(pos<1)=1;
            
            ind1=pos(2)+(1:pos(4))-1;
            ind2=pos(1)+(1:pos(3))-1;
            ind1(ind1<1)=[];
            ind1(ind1>app.currentData.ydim)=[];
            ind2(ind2<1)=[];
            ind2(ind2>app.currentData.xdim)=[];
            spec=squeeze(sum(app.currentData.data(ind1,ind2,:),[1 2]));
            ax2=subplot('Position',[0.45 0.15 0.5 0.8]);
            hold(ax2,'on')
            % plot spectrum
            pltspec=plot(ax2,app.currentData.ene,spec','k.','linewidth',1.5);
            pltbg=plot(ax2,app.currentData.ene,spec'*0,'r','linewidth',1.5);
            pltsgnl=plot(ax2,app.currentData.ene,spec','-','color',[0 0.8 0.4],'linewidth',1.5);
            %             fillsgnl=fill(ax2,app.currentData.ene,spec',spec','edgecolor','none');
            maxval=max(spec(app.currentData.ene>app.FromEditField1.Value&app.currentData.ene<app.toEditField2.Value));
            set(gcf,'toolbar','auto')
            beautifyfig('none',1)
            ylim([-0.1 1.5]*maxval)
            xlabel('Energy (meV)')
            ylabel('Intensity (a.u.)')
            legend Raw BG Signal
            xlim(ax2,[max(-100,app.currentData.ene(1)),min(300,app.currentData.ene(end))])
            Ylm=[-1e10,1e10];
            rct1=imrect(gca,[app.FromEditField1.Value,Ylm(1),app.toEditField1.Value-app.FromEditField1.Value, Ylm(2)-Ylm(1)]);
            rct2=imrect(gca,[app.FromEditField2.Value,Ylm(1),app.toEditField2.Value-app.FromEditField2.Value, Ylm(2)-Ylm(1)]);
            fcn = makeConstrainToRectFcn('imrect',[-100,max(app.currentData.ene)],Ylm);
            setPositionConstraintFcn(rct1,fcn);
            setPositionConstraintFcn(rct2,fcn);
            fcn = makeConstrainToRectFcn('imrect',[0.5,app.currentData.xdim+0.5],[0.5,app.currentData.ydim+0.5]);
            setPositionConstraintFcn(rct,fcn);
            %             colormap(ax2,1-flip(hot_s))
            addNewPositionCallback(rct2,@(p) plotfitbg(rct1.getPosition,p,rct.getPosition));
            addNewPositionCallback(rct1,@(p) plotfitbg(p,rct2.getPosition,rct.getPosition));
            addNewPositionCallback(rct,@(p) plotfitbg(rct1.getPosition,rct2.getPosition,p));
            plotfitbg(rct1.getPosition,rct2.getPosition,rct.getPosition)
            function []=plotfitbg(pos1,pos2,pos)
                pos=round(pos+[0.5 0.5 0 0]);
                pos(pos<1)=1;
                ind1=pos(2)+(1:pos(4))-1;
                ind2=pos(1)+(1:pos(3))-1;
                ind1(ind1<1)=[];
                ind1(ind1>app.currentData.ydim)=[];
                ind2(ind2<1)=[];
                ind2(ind2>app.currentData.xdim)=[];
                spec=squeeze(mean(app.currentData.data(ind1,ind2,:),[1 2]));
                bg=fitbg(app.currentData.ene,spec,pos1,pos2,app.FittypeDropDown.Value);
                pltbg.YData=bg;
                pltspec.YData=spec;
                sgnl=spec(:)-bg(:);
                pltsgnl.YData=sgnl;
                %                 fillsgnl.YData=sgnl;
                %                 fillsgnl.CData=sgnl;
                
                from1=pos1(1);
                from2=pos2(1);
                to1=pos1(1)+pos1(3);
                to2=pos2(1)+pos2(3);
                %                 set(ax2,'clim',[-1 1]*max(abs(sgnl(app.currentData.ene>to1&app.currentData.ene<from2))))
                app.FromEditField1.Value=round(from1);
                app.FromEditField2.Value=round(from2);
                app.toEditField1.Value=round(to1);
                app.toEditField2.Value=round(to2);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            answer = uiconfirm(app.UIFigure,'Do you really wish to exit? Unsaved results will be lost!', ...
               	'Comfirm?', ...
               	'Options',{'Exit','Cancel'},'DefaultOption',2);
            if strcmp(answer,'Exit')
%                 warning('on','all')
                delete(app.previewfig)
                delete(app)
            else
                close(app.UIFigure.UserData)
            end
        end

        % Menu selected function: ExporttoworkspaceMenu
        function Export2WSButtonPushed(app, event)
            prompt = {'Name:'};
            dlgtitle = 'Export data to workspace';
            dims = [1 35];
            definput = {'ExportedData'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if ~isempty(answer)
                assignin('base',answer{1},app.currentData)
            end
        end

        % Callback function
        function CommandDropdownValueChanged(app, event)
            value = app.CommandDropdown.Value;
            app.CommandEditField.Value=value;
        end

        % Callback function: SaveasMenu, SavecurrentdataButton
        function SaveasMenuSelected(app, event)
            app.setbusy
            data=app.currentData;
            ind=find(app.filename=='.');ind=ind(end);
            [file,path] = uiputfile([app.filename(1:ind-1),'.mat']);
            try
                save([path,file],'data','-mat')
                disp(['Data successfully saved to ',[path,file]])
            catch
%                 errordlg('Saving failed')
            end
            app.setready
        end

        % Menu selected function: RestoredefaultparametersMenu
        function RestoredefaultparametersMenuSelected(app, event)
            delete([app.toolboxpath,'/3DEELS/Process/defaultconfig.mat'])
        end

        % Menu selected function: RemoveoutliersMenu
        function RemoveoutliersMenuSelected(app, event)
            app.updatedata(RemoveOutliers3D(app.currentData),'Remove outliers');
        end

        % Menu selected function: ImportfromworkspaceMenu
        function ImportfromworkspaceMenuSelected(app, event)
            vars = evalin('base', 'whos');
            cell_array = {};
            for i=1:length(vars)
                iseels=evalin('base', ['strcmp("EELS",class(',vars(i).name,'))']);
                if iseels
                    cell_array = [cell_array,vars(i).name];
                end
            end
            [index,tf]=listdlg('ListString',cell_array,'PromptString','Select data','SelectionMode','single','Name','Data Selection');
            if tf
                app.updatedata(evalin('base',cell_array{index}),'Import from WS');
                if isempty(app.filename)
                    app.filename=[app.toolboxpath,'\fromworkspace.mat'];
                end
                uialert(app.UIFigure,'Be careful about the path when saving the data','Warning','Icon','warning')
                app.updatepreview;
            end
            
        end

        % Menu selected function: CropMenu
        function CropMenuSelected(app, event)
            app.updatedata(CropData_GUI(app.currentData),'Crop data');
        end

        % Menu selected function: SetenergylimitMenu
        function SetenergylimitMenuSelected(app, event)
            prompt = {'Lower (meV):','Upper (meV):'};
            dlgtitle = 'Energy limit';
            dims = [1 35];
            definput = {'',''};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            try
                answer=[answer{1},'  ',answer{2}];
                app.SetenergylimitMenu.Tooltip=answer;
                app.elim=str2num(answer);
            catch
            end
        end

        % Menu selected function: PixelsizeMenu
        function PixelsizeMenuSelected(app, event)
            prompt = {'Pixel size (nm):'};
            dlgtitle = 'Change pixel size';
            dims = [1 35];
            definput = {num2str(app.currentData.xscale)};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if ~isempty(answer)
                app.currentData.info.xscale=str2num(answer{1});app.currentData.info.yscale=str2num(answer{1});
                for ii=1:numel(app.history)
                    try
                        app.history(ii).info.xscale=str2num(answer{1});app.history(ii).info.yscale=str2num(answer{1});
                    catch
                    end
                end
            end
        end

        % Menu selected function: FilepathMenu
        function FilepathMenuSelected(app, event)
            prompt = {'File name:'};
            dlgtitle = 'Change path';
            dims = [1 35];
            definput = {app.filename};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if ~isempty(answer)
                app.filename=answer{1};
                app.FilepathMenu.Tooltip=answer{1};
            end
        end

        % Menu selected function: SpectralsamplingMenu
        function SpectralsamplingMenuSelected(app, event)
            
        end

        % Menu selected function: RefreshMenu
        function RefreshMenuSelected(app, event)
            temp=ls([app.toolboxpath,'\3DEELS\Custom\Process']);
            funlist={};
            for ii=1:size(temp,1)
                funname=temp(ii,:);
                funname=funname(~isspace(funname));
                if strcmp(funname(end),'m')
                    funlist=[funlist,funname];
                end
            end
            m = app.ProcessingfunctionsMenu;
            delete(m.Children)
            for ii=1:numel(funlist)
                mitem = uimenu(m,'Text',funlist{ii});
                mitem.MenuSelectedFcn = @helpfun1;
            end
            
            temp=ls([app.toolboxpath,'\3DEELS\Custom\Visualize']);
            funlist={};
            for ii=1:size(temp,1)
                funname=temp(ii,:);
                funname=funname(~isspace(funname));
                if strcmp(funname(end),'m')
                    funlist=[funlist,funname];
                end
            end
            m = app.VisualizingfunctionsMenu;
            delete(m.Children)
            for ii=1:numel(funlist)
                mitem = uimenu(m,'Text',funlist{ii});
                mitem.MenuSelectedFcn = @helpfun2;
            end
            
            temp=ls([app.toolboxpath,'\3DEELS\Custom\Executable']);
            funlist={};
            for ii=1:size(temp,1)
                funname=temp(ii,:);
                funname=funname(~isspace(funname));
                if strcmp(funname(end),'m')
                    funlist=[funlist,funname];
                end
            end
            m = app.ExecutableMenu;
            delete(m.Children)
            for ii=1:numel(funlist)
                mitem = uimenu(m,'Text',funlist{ii});
                mitem.MenuSelectedFcn = @helpfun3;
            end
            
            function helpfun1(src,~)
                app.setbusy
                fun=src.Text;
                fun=fun(1:end-2);
                app.updatedata(eval([fun,'(app.currentData)']),[fun]);
                app.setready
            end
            function helpfun2(src,~)
                app.setbusy
                fun=src.Text;
                fun=fun(1:end-2);
                eval([fun,'(app.currentData)'])
                app.setready
            end
            function helpfun3(src,~)
                app.setbusy
                eval(fileread(src.Text))
                app.setready
            end
        end

        % Callback function
        function UpdatepreviewMenuSelected(app, event)
            app.updatepreview
        end

        % Menu selected function: LogscaleMenu
        function LogscaleMenuSelected(app, event)
            if strcmp(app.LogscaleMenu.Checked,'off')
                set(app.previewfig.UserData(1),'yscale','log')
                app.LogscaleMenu.Checked='on';
            else
                set(app.previewfig.UserData(1),'yscale','linear')
                app.LogscaleMenu.Checked='off';
            end
        end

        % Button pushed function: SaveallhistoryButton
        function SaveallhistoryButtonPushed(app, event)
            app.setbusy
            hist=app.history;
            ind=find(app.filename=='.');ind=ind(end);
            [file,path] = uiputfile([app.filename(1:ind-1),'.mat']);
            try
                save([path,file],'hist','-mat')
                disp(['Data successfully saved to ',[path,file]])
            catch
%                 errordlg('Saving failed')
            end
            app.setready
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Vibrational EELS Toolbox 1.1.0 prerelease';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportMenu.Accelerator = 'I';
            app.ImportMenu.Text = 'Import';
            app.ClearMenu = uimenu(app.FileMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearMenu.Text = 'Clear';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Accelerator = 'S';
            app.SaveasMenu.Text = 'Save as';
            app.ImportfromworkspaceMenu = uimenu(app.FileMenu);
            app.ImportfromworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportfromworkspaceMenuSelected, true);
            app.ImportfromworkspaceMenu.Text = 'Import from workspace';
            app.ExporttoworkspaceMenu = uimenu(app.FileMenu);
            app.ExporttoworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @Export2WSButtonPushed, true);
            app.ExporttoworkspaceMenu.Accelerator = 'E';
            app.ExporttoworkspaceMenu.Text = 'Export to workspace';
            app.InfoMenu = uimenu(app.UIFigure);
            app.InfoMenu.Text = 'Info';
            app.DatasizeMenu = uimenu(app.InfoMenu);
            app.DatasizeMenu.Enable = 'off';
            app.DatasizeMenu.Text = 'Data size';
            app.PixelsizeMenu = uimenu(app.InfoMenu);
            app.PixelsizeMenu.MenuSelectedFcn = createCallbackFcn(app, @PixelsizeMenuSelected, true);
            app.PixelsizeMenu.Text = 'Pixel size';
            app.SpectralsamplingMenu = uimenu(app.InfoMenu);
            app.SpectralsamplingMenu.MenuSelectedFcn = createCallbackFcn(app, @SpectralsamplingMenuSelected, true);
            app.SpectralsamplingMenu.Enable = 'off';
            app.SpectralsamplingMenu.Text = 'Spectral sampling';
            app.FilepathMenu = uimenu(app.InfoMenu);
            app.FilepathMenu.MenuSelectedFcn = createCallbackFcn(app, @FilepathMenuSelected, true);
            app.FilepathMenu.Text = 'File path';
            app.ProcessMenu = uimenu(app.UIFigure);
            app.ProcessMenu.Text = 'Process';
            app.RemoveoutliersMenu = uimenu(app.ProcessMenu);
            app.RemoveoutliersMenu.MenuSelectedFcn = createCallbackFcn(app, @RemoveoutliersMenuSelected, true);
            app.RemoveoutliersMenu.Text = 'Remove outliers';
            app.CropMenu = uimenu(app.ProcessMenu);
            app.CropMenu.MenuSelectedFcn = createCallbackFcn(app, @CropMenuSelected, true);
            app.CropMenu.Text = 'Crop';
            app.DisplayMenu = uimenu(app.UIFigure);
            app.DisplayMenu.Text = 'Display';
            app.SetenergylimitMenu = uimenu(app.DisplayMenu);
            app.SetenergylimitMenu.MenuSelectedFcn = createCallbackFcn(app, @SetenergylimitMenuSelected, true);
            app.SetenergylimitMenu.Text = 'Set energy limit';
            app.LogscaleMenu = uimenu(app.DisplayMenu);
            app.LogscaleMenu.MenuSelectedFcn = createCallbackFcn(app, @LogscaleMenuSelected, true);
            app.LogscaleMenu.Text = 'Log scale';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.SavecurrentsettingsMenu = uimenu(app.OptionsMenu);
            app.SavecurrentsettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasdefaultButtonPushed, true);
            app.SavecurrentsettingsMenu.Text = 'Save current settings';
            app.RestoredefaultparametersMenu = uimenu(app.OptionsMenu);
            app.RestoredefaultparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @RestoredefaultparametersMenuSelected, true);
            app.RestoredefaultparametersMenu.Text = 'Restore default parameters';
            app.CustomMenu = uimenu(app.UIFigure);
            app.CustomMenu.Text = 'Custom';
            app.ProcessingfunctionsMenu = uimenu(app.CustomMenu);
            app.ProcessingfunctionsMenu.Text = 'Processing functions';
            app.VisualizingfunctionsMenu = uimenu(app.CustomMenu);
            app.VisualizingfunctionsMenu.Text = 'Visualizing functions';
            app.ExecutableMenu = uimenu(app.CustomMenu);
            app.ExecutableMenu.Text = 'Executable';
            app.RefreshMenu = uimenu(app.CustomMenu);
            app.RefreshMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshMenuSelected, true);
            app.RefreshMenu.Text = 'Refresh';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Tooltip = {'Import a .dm3 file or a .mat file'};
            app.ImportButton.Text = 'Import';
            app.DatahistoryDropDownLabel = uilabel(app.UIFigure);
            app.DatahistoryDropDownLabel.Text = 'Data history';
            app.DatahistoryDropDown = uidropdown(app.UIFigure);
            app.DatahistoryDropDown.Items = {};
            app.DatahistoryDropDown.ValueChangedFcn = createCallbackFcn(app, @DatahistoryDropDownValueChanged, true);
            app.DatahistoryDropDown.Tooltip = {'Undo or redo previous steps.'};
            app.DatahistoryDropDown.Value = {};
            app.DevelopedbyRuishiQiLabel = uilabel(app.UIFigure);
            app.DevelopedbyRuishiQiLabel.Tooltip = {'Email: rs-qi@pku.edu.cn'};
            app.DevelopedbyRuishiQiLabel.Text = 'Developed by Ruishi Qi';
            app.VisualizeyourdataPanel = uipanel(app.UIFigure);
            app.VisualizeyourdataPanel.Title = 'Visualize your data';
            app.LineprofileButton = uibutton(app.VisualizeyourdataPanel, 'push');
            app.LineprofileButton.ButtonPushedFcn = createCallbackFcn(app, @LineprofileButtonPushed, true);
            app.LineprofileButton.Text = 'Line profile';
            app.VisualizerDropDownLabel = uilabel(app.VisualizeyourdataPanel);
            app.VisualizerDropDownLabel.Text = 'Visualizer';
            app.VisualizerDropDown = uidropdown(app.VisualizeyourdataPanel);
            app.VisualizerDropDown.Items = {'False color map', 'Line plot', 'Both'};
            app.VisualizerDropDown.ItemsData = {'1', '2', '3'};
            app.VisualizerDropDown.Value = '1';
            app.SumalongDropDownLabel = uilabel(app.VisualizeyourdataPanel);
            app.SumalongDropDownLabel.Text = 'Sum along';
            app.SumalongDropDown = uidropdown(app.VisualizeyourdataPanel);
            app.SumalongDropDown.Items = {'x', 'y'};
            app.SumalongDropDown.ItemsData = {'1', '2'};
            app.SumalongDropDown.Value = '1';
            app.meVLabel_6 = uilabel(app.VisualizeyourdataPanel);
            app.meVLabel_6.Text = 'meV';
            app.MapsignalButton = uibutton(app.VisualizeyourdataPanel, 'push');
            app.MapsignalButton.ButtonPushedFcn = createCallbackFcn(app, @MapsignalButtonPushed, true);
            app.MapsignalButton.Text = 'Map signal';
            app.WidthEditFieldLabel = uilabel(app.VisualizeyourdataPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.VisualizeyourdataPanel, 'numeric');
            app.WidthEditField.Limits = [0 Inf];
            app.WidthEditField.Value = 2;
            app.EnergyEditFieldLabel = uilabel(app.VisualizeyourdataPanel);
            app.EnergyEditFieldLabel.Text = 'Energy';
            app.EnergyEditField = uieditfield(app.VisualizeyourdataPanel, 'text');
            app.EnergyEditField.Value = '71:2:83';
            app.Label = uilabel(app.VisualizeyourdataPanel);
            app.Label.Text = '=';
            app.SumRegionEditField = uieditfield(app.VisualizeyourdataPanel, 'text');
            app.SumRegionEditField.Value = '1:end';
            app.ProcessyourdataPanel = uipanel(app.UIFigure);
            app.ProcessyourdataPanel.Title = 'Process your data';
            app.DeconvolutionButton = uibutton(app.ProcessyourdataPanel, 'push');
            app.DeconvolutionButton.ButtonPushedFcn = createCallbackFcn(app, @DeconvolutionButtonPushed, true);
            app.DeconvolutionButton.Text = 'Deconvolution';
            app.IterationSpinnerLabel = uilabel(app.ProcessyourdataPanel);
            app.IterationSpinnerLabel.Text = 'Iteration';
            app.IterationSpinner = uispinner(app.ProcessyourdataPanel);
            app.IterationSpinner.Limits = [1 Inf];
            app.IterationSpinner.RoundFractionalValues = 'on';
            app.IterationSpinner.Value = 10;
            app.SubtractBackgroundButton = uibutton(app.ProcessyourdataPanel, 'push');
            app.SubtractBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @SubtractBackgroundButtonPushed, true);
            app.SubtractBackgroundButton.Text = {'Subtract'; 'Background'};
            app.FromEditField1 = uieditfield(app.ProcessyourdataPanel, 'numeric');
            app.FromEditField1.Value = 40;
            app.meVLabel_7 = uilabel(app.ProcessyourdataPanel);
            app.meVLabel_7.Text = 'meV';
            app.FromEditField2 = uieditfield(app.ProcessyourdataPanel, 'numeric');
            app.FromEditField2.Value = 100;
            app.toEditFieldLabel = uilabel(app.ProcessyourdataPanel);
            app.toEditFieldLabel.Text = 'to';
            app.toEditField2 = uieditfield(app.ProcessyourdataPanel, 'numeric');
            app.toEditField2.Value = 120;
            app.toEditField_2Label = uilabel(app.ProcessyourdataPanel);
            app.toEditField_2Label.Text = 'to';
            app.toEditField1 = uieditfield(app.ProcessyourdataPanel, 'numeric');
            app.toEditField1.Value = 60;
            app.FittypeDropDownLabel = uilabel(app.ProcessyourdataPanel);
            app.FittypeDropDownLabel.Text = 'Fittype:';
            app.FittypeDropDown = uidropdown(app.ProcessyourdataPanel);
            app.FittypeDropDown.Items = {'exp(a*x^3+b*x^2+c*x+d)', 'exp(a*x^4+...+e)', 'exp(a*x^2+b*x+c)', 'a*x^b', 'a*exp(b*x)+c*exp(d*x)', 'Pearson'};
            app.FittypeDropDown.ItemsData = {'ExpPoly3', 'ExpPoly4', 'ExpPoly2', 'Power', 'Exponential_2', 'Pearson'};
            app.FittypeDropDown.Value = 'ExpPoly3';
            app.BM3DdenoiseButton = uibutton(app.ProcessyourdataPanel, 'push');
            app.BM3DdenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @BM3DdenoiseButtonPushed, true);
            app.BM3DdenoiseButton.Text = 'BM3D denoise';
            app.meVLabel_9 = uilabel(app.ProcessyourdataPanel);
            app.meVLabel_9.Text = 'meV';
            app.toEditField_3Label = uilabel(app.ProcessyourdataPanel);
            app.toEditField_3Label.Text = 'to';
            app.BM3DtoEditField = uieditfield(app.ProcessyourdataPanel, 'numeric');
            app.BM3DtoEditField.Value = 120;
            app.FromEditField_3Label = uilabel(app.ProcessyourdataPanel);
            app.FromEditField_3Label.Text = 'From';
            app.BM3DFromEditField = uieditfield(app.ProcessyourdataPanel, 'numeric');
            app.BM3DFromEditField.Value = 40;
            app.NormalizeButton = uibutton(app.ProcessyourdataPanel, 'push');
            app.NormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizeButtonPushed, true);
            app.NormalizeButton.Text = 'Normalize';
            app.RemovebadpointsinsidethesampleCheckBox = uicheckbox(app.ProcessyourdataPanel);
            app.RemovebadpointsinsidethesampleCheckBox.Enable = 'off';
            app.RemovebadpointsinsidethesampleCheckBox.Visible = 'off';
            app.RemovebadpointsinsidethesampleCheckBox.Text = 'Remove bad points inside the sample';
            app.AlignButton = uibutton(app.ProcessyourdataPanel, 'push');
            app.AlignButton.ButtonPushedFcn = createCallbackFcn(app, @AlignButtonPushed, true);
            app.AlignButton.Text = 'Align';
            app.DropDown = uidropdown(app.ProcessyourdataPanel);
            app.DropDown.Items = {'Cross correlation', 'Center of mass', 'Gaussian convolution'};
            app.DropDown.Value = 'Cross correlation';
            app.MethodLabel = uilabel(app.ProcessyourdataPanel);
            app.MethodLabel.Text = 'Method:';
            app.SavecurrentdataButton = uibutton(app.ProcessyourdataPanel, 'push');
            app.SavecurrentdataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SavecurrentdataButton.Text = 'Save current data';
            app.andLabel = uilabel(app.ProcessyourdataPanel);
            app.andLabel.Text = 'and';
            app.ManuallyadjustenergywindowsButton = uibutton(app.ProcessyourdataPanel, 'push');
            app.ManuallyadjustenergywindowsButton.ButtonPushedFcn = createCallbackFcn(app, @ManuallyadjustenergywindowsButtonPushed, true);
            app.ManuallyadjustenergywindowsButton.Text = 'Manually adjust energy windows';
            app.SaveallhistoryButton = uibutton(app.ProcessyourdataPanel, 'push');
            app.SaveallhistoryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallhistoryButtonPushed, true);
            app.SaveallhistoryButton.Text = 'Save all history';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EELS3D_TOOLBOX

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end