classdef EELS4D_TOOLBOX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ImportMenu                      matlab.ui.container.Menu
        ClearMenu                       matlab.ui.container.Menu
        SaveasMenu                      matlab.ui.container.Menu
        ImportfromworkspaceMenu         matlab.ui.container.Menu
        ExporttoworkspaceMenu           matlab.ui.container.Menu
        UndoredoMenu                    matlab.ui.container.Menu
        RotateMenu                      matlab.ui.container.Menu
        clockwiseMenu                   matlab.ui.container.Menu
        counterclockwiseMenu            matlab.ui.container.Menu
        FlipMenu                        matlab.ui.container.Menu
        xMenu                           matlab.ui.container.Menu
        yMenu                           matlab.ui.container.Menu
        InfoMenu                        matlab.ui.container.Menu
        DatasizeMenu                    matlab.ui.container.Menu
        PixelsizeMenu                   matlab.ui.container.Menu
        SpectralsamplingMenu            matlab.ui.container.Menu
        FilepathMenu                    matlab.ui.container.Menu
        AvailablememoryMenu             matlab.ui.container.Menu
        UsedmemoryMenu                  matlab.ui.container.Menu
        DisplayMenu                     matlab.ui.container.Menu
        SetelimMenu                     matlab.ui.container.Menu
        UpdatepreviewMenu               matlab.ui.container.Menu
        UsecolormapMenu                 matlab.ui.container.Menu
        UselineplotMenu                 matlab.ui.container.Menu
        OptionsMenu                     matlab.ui.container.Menu
        WritelogfileMenu                matlab.ui.container.Menu
        AlignmentMenu                   matlab.ui.container.Menu
        BinningsizeMenu                 matlab.ui.container.Menu
        EnforceBM3DMenu                 matlab.ui.container.Menu
        DenoisefactorMenu               matlab.ui.container.Menu
        RestoredefaultparametersMenu    matlab.ui.container.Menu
        CustomMenu                      matlab.ui.container.Menu
        ProcessingfunctionsMenu         matlab.ui.container.Menu
        VisualizingfunctionsMenu        matlab.ui.container.Menu
        ExecutableMenu                  matlab.ui.container.Menu
        RefreshMenu                     matlab.ui.container.Menu
        ImportButton                    matlab.ui.control.Button
        ClearButton                     matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        UndoButton                      matlab.ui.control.Button
        DatapreviewPanel                matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        brighter_upper                  matlab.ui.control.Button
        dimmer_upper                    matlab.ui.control.Button
        brighter_lower                  matlab.ui.control.Button
        dimmer_lower                    matlab.ui.control.Button
        DataprocessingPanel             matlab.ui.container.Panel
        AlignButton                     matlab.ui.control.Button
        RemovedarkcurrentButton         matlab.ui.control.Button
        CropButton                      matlab.ui.control.Button
        BM3DButton                      matlab.ui.control.Button
        BinButton                       matlab.ui.control.Button
        FillbadchannelsButton           matlab.ui.control.Button
        StatusReadyLabel                matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        DevelopedbyRuishiQiLabel        matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        DynamicalplotTab                matlab.ui.container.Tab
        STAcorrectionCheckBox           matlab.ui.control.CheckBox
        SelectXYButton                  matlab.ui.control.Button
        SelectQEButton                  matlab.ui.control.Button
        SelectXQButton                  matlab.ui.control.Button
        SelectYQButton                  matlab.ui.control.Button
        SliceTab                        matlab.ui.container.Tab
        SliceButton                     matlab.ui.control.Button
        SumoverDropDownLabel            matlab.ui.control.Label
        SumoverDropDown                 matlab.ui.control.DropDown
        qsamplingEditFieldLabel         matlab.ui.control.Label
        qsamplingEditField              matlab.ui.control.NumericEditField
        IntegrationwidthEditFieldLabel  matlab.ui.control.Label
        IntegrationwidthEditField       matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        sumregionEditField              matlab.ui.control.EditField
    end

    
    properties (Access = public)
        previousData
        currentData
        filename
        toolboxpath
        tag='';
    end
    
    properties (Access = private)
        
        EELS3DApp % Description
        elim=[];
    end
    
    methods (Access = public)
        
        function [] = setbusy(app)
            app.StatusReadyLabel.Text='Status: Busy';
            app.StatusReadyLabel.FontColor=[1 0 0];
            drawnow
        end
        function [] = setready(app)
            app.StatusReadyLabel.Text='Status: Ready';
            app.StatusReadyLabel.FontColor=[0.22,0.67,0.19];
            if ~isempty(app.previousData)
                app.UndoButton.Enable=1;
            else
                app.UndoButton.Enable=0;
            end
            if ~isempty(app.currentData)
                enableall=1;
            else
                enableall=0;
            end
            app.SaveButton.Enable=enableall;
            app.AlignButton.Enable=enableall;
            app.BM3DButton.Enable=enableall;
            app.BinButton.Enable=enableall;
            app.CropButton.Enable=enableall;
            app.FillbadchannelsButton.Enable=enableall;
            app.SelectQEButton.Enable=enableall;
            app.SelectXYButton.Enable=enableall;
            app.SelectXQButton.Enable=enableall;
            app.SelectYQButton.Enable=enableall;
            app.RemovedarkcurrentButton.Enable=enableall;
            app.SliceButton.Enable=enableall;
            app.SaveasMenu.Enable=enableall;
            app.ExporttoworkspaceMenu.Enable=enableall;
            app.RotateMenu.Enable=enableall;
            app.FlipMenu.Enable=enableall;
        end
        function []=updatepreview(app)
            if ~isempty(app.currentData)
                sumall=proj2qe(app.currentData);
                img=sumall.data;
                if app.STAcorrectionCheckBox.Value
                    sta=app.currentData.sta;
                    img=img./sta';
                end
                maxval=prctile(img(:),95);
                minval=max(prctile(img(:),5),0);
                imagesc(app.UIAxes,[],app.currentData.ene,img,[minval,maxval])
                colormap(app.UIAxes,jet2)
                axis(app.UIAxes,'tight')
                axis(app.UIAxes,'xy')
                app.UIAxes.Toolbar.Visible = 'off';
                app.UIAxes_2.Toolbar.Visible = 'off';
                disableDefaultInteractivity(app.UIAxes_2)
                box(app.UIAxes,'on')
                if ~isempty(app.elim)&&app.STAcorrectionCheckBox.Value
                    ylim(app.UIAxes,app.elim)
                end
                map=proj2xy(app.currentData);
                imagesc(app.UIAxes_2,map,[0 max(map(:))])
                axis(app.UIAxes_2,'image')
                axis(app.UIAxes_2,'off')
                colormap(app.UIAxes_2,hot)
                try
                    set(findall(app.UIAxes,'-property','FontName'),'FontName','lm sans 10')
                catch
                end
                app.DatapreviewPanel.Title=['Data preview (',num2str(app.currentData.ydim),'x',num2str(app.currentData.xdim),'x',num2str(app.currentData.edim),'x',num2str(app.currentData.qdim),')'];
            else
                app.DatapreviewPanel.Title=['Data preview'];
            end
            if ~isempty(app.currentData)
                app.DatasizeMenu.Tooltip=[num2str(app.currentData.ydim),'x',num2str(app.currentData.xdim),'x',num2str(app.currentData.edim),'x',num2str(app.currentData.qdim)];
                app.PixelsizeMenu.Tooltip=[num2str(app.currentData.xscale),' nm/pixel'];
                app.SpectralsamplingMenu.Tooltip=[num2str(app.currentData.escale),' meV/channel'];
                app.FilepathMenu.Tooltip=app.filename;
            else
                app.DatasizeMenu.Tooltip='';
                app.PixelsizeMenu.Tooltip='';
                app.SpectralsamplingMenu.Tooltip='';
                app.FilepathMenu.Tooltip='';
            end
            m=memory;
            app.AvailablememoryMenu.Tooltip=[num2str(m.MemAvailableAllArrays/1024^3,3),' GB available'];
            app.UsedmemoryMenu.Tooltip=[num2str(m.MemUsedMATLAB/1024^3,3),' GB used'];
            if m.MemAvailableAllArrays/1024^3<2
                app.AvailablememoryMenu.ForegroundColor=[1 0 0];
            end
            app.setready
        end
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off','all')
            [directory,~,~] = fileparts(mfilename('fullpath'));
            addpath(genpath(directory))
            app.toolboxpath=directory;
            app.STAcorrectionCheckBox.Value=0;
            app.RestoredefaultparametersMenuSelected;
            app.Label2.Text='No file loaded';
            app.RefreshMenuSelected
            app.setready;
        end

        % Callback function: ImportButton, ImportMenu
        function ImportButtonPushed(app, event)
            app.setbusy;
            diary off
            if ~isempty(app.currentData)
                %                 set(0,'DefaultUicontrolBackgroundColor','w')
                answer = questdlg('Loading a new dataset will overwrite current one. Unsaved results will be lost!', ...
                   	'Comfirm?', ...
                   	'Overwrite','Cancel','Overwrite');
                % Handle response
                if strcmp(answer,'Cancel')
                    app.setready;
                    return
                end
            end
            [file,path] = uigetfile({'*'});
            app.filename=[path,file];
            if file==0
                app.setready;
                return
            else
                app.previousData=[];
                app.currentData=[];
                app.tag=[];
                app.setready;
            end
            ind=find(file=='.');ind=ind(end);
            if app.WritelogfileMenu.Checked
                diaryname=[file(1:ind-1),'_log file ',datestr(datetime('now')),'.txt'];diaryname(diaryname==':')='.';
                %             disp(diaryname)
                diary([path,diaryname])
            end
            if strcmp(file(end-2:end),'npy')||strcmp(file(end-3:end),'json')
                try
                    app.currentData = readNPYtoEELS( [path,file] );
                catch
                    errordlg('Fail to load NPY file')
                end
            elseif strcmp(file(end-2:end),'mat')
                load([path,file],'-mat','data','datatag')
                if (exist('datatag')~=1)
                    datatag='';
                end
                app.currentData=data;
                app.tag=datatag;
                try
                    app.filename=erase(app.filename,datatag);
                catch
                end
            elseif strcmp(file(end-2:end),'dm3')
                try
                    app.currentData = DM3ImportRecording( [path,file] );
                catch
                    errordlg('Fail to load DM3 file')
                end
            else
                errordlg('Unsupported file type')
                return
            end
            app.updatepreview;
            app.Label2.Text=file;
            app.UndoButton.Text='Undo';
            app.setready;
        end

        % Callback function
        function RunButtonPushed(app, event)
            disp(['>> ',app.CommandEditField.Value])
            eval(app.CommandEditField.Value)
        end

        % Callback function: ClearButton, ClearMenu
        function ClearButtonPushed(app, event)
            disp('===== ClearBottunPushed =====')
            app.setbusy;
            answer = questdlg('Do you wish to clear all data? Unsaved results will be lost!', ...
               	'Comfirm?', ...
               	'Clear','Cancel','Clear');
            % Handle response
            if strcmp(answer,'Cancel')
                app.setready;
                return
            end
            %             clear
            app.previousData=[];
            app.currentData=[];
            app.filename=[];
            app.tag=[];
            cla(app.UIAxes)
            axis(app.UIAxes,[0 1 0 1])
            cla(app.UIAxes_2)
            axis(app.UIAxes_2,[0 1 0 1])
            axis(app.UIAxes_2,'on','xy')
            app.UndoButton.Text='Undo';
            app.setready;
        end

        % Button pushed function: RemovedarkcurrentButton
        function RemovedarkcurrentButtonPushed(app, event)
            disp('===== RemoveDarkCurrentButtonPushed =====')
            app.setbusy;
            app.previousData=app.currentData;
            app.currentData=RemoveDarkCurrent(app.currentData);
            app.updatepreview;
            %             app.tag=[app.tag,'_DCRem'];
            app.UndoButton.Text='Undo';
            app.setready;
            
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            disp('===== CropButtonPushed =====')
            app.setbusy;
            app.previousData=app.currentData;
            app.currentData=Crop4DEELS(app.currentData);
            app.updatepreview
            %             app.tag=[app.tag,'_Crop'];
            app.UndoButton.Text='Undo';
            app.setready;
        end

        % Button pushed function: AlignButton
        function AlignButtonPushed(app, event)
            disp('===== AlignButtonPushed =====')
            app.setbusy;
            app.previousData=app.currentData;
            val=str2num(app.AlignmentMenu.Tooltip);
            app.currentData=Align4DEELS(app.currentData,val(1),val(2));
            app.updatepreview
            app.STAcorrectionCheckBox.Value=1;
            app.tag=[app.tag,'_Align'];
            app.UndoButton.Text='Undo';
            app.setready;
        end

        % Button pushed function: BM3DButton
        function BM3DButtonPushed(app, event)
            disp('===== BM3DDenoiseButtonPushed =====')
            app.setbusy;
            app.previousData=app.currentData;
            if strcmp(app.EnforceBM3DMenu.Checked,'on')
                app.currentData=Denoise4DEELS2(app.currentData,str2num(app.DenoisefactorMenu.Tooltip));
            else
                app.currentData=Denoise4DEELS(app.currentData,str2num(app.DenoisefactorMenu.Tooltip));
            end
            app.updatepreview
            app.tag=[app.tag,'_BM3D'];
            app.UndoButton.Text='Undo';
            app.setready;
        end

        % Button pushed function: BinButton
        function BinButtonPushed(app, event)
            disp('===== BinDataButtonPushed =====')
            app.setbusy;
            app.previousData=app.currentData;
            binningsize=str2num(app.BinningsizeMenu.Tooltip);
            app.currentData=Bin4DEELS(app.currentData,binningsize([2 1 3 4]));
            app.updatepreview
            app.tag=[app.tag,'_Bin'];
            app.UndoButton.Text='Undo';
            app.setready;
        end

        % Button pushed function: SelectXYButton
        function SelectXYButtonPushed(app, event)
            disp('===== SelectXYButtonPushed =====')
            app.setbusy
            SelectXY(app.currentData,app.STAcorrectionCheckBox.Value)
            app.setready
        end

        % Button pushed function: SelectQEButton
        function SelectQEButtonPushed(app, event)
            disp('===== SelectQEButtonPushed =====')
            app.setbusy
            SelectQE(app.currentData,app.STAcorrectionCheckBox.Value)
            app.setready
        end

        % Button pushed function: SelectXQButton
        function SelectXQButtonPushed(app, event)
            disp('===== SelectXQButtonPushed =====')
            app.setbusy
            if strcmp(app.UsecolormapMenu.Checked,'on')
                SelectXQ(app.currentData,app.STAcorrectionCheckBox.Value)
            else
                SelectXQLine(app.currentData,app.STAcorrectionCheckBox.Value)
            end
            app.setready
        end

        % Button pushed function: SelectYQButton
        function SelectYQButtonPushed(app, event)
            disp('===== SelectYQButtonPushed =====')
            app.setbusy
            if strcmp(app.UsecolormapMenu.Checked,'on')
                SelectYQ(app.currentData,app.STAcorrectionCheckBox.Value)
            else
                SelectYQLine(app.currentData,app.STAcorrectionCheckBox.Value)
            end
            app.setready
        end

        % Callback function: UndoButton, UndoredoMenu
        function UndoButtonPushed(app, event)
            disp('===== UndoButtonPushed =====')
            app.setbusy
            if isempty(app.previousData)
                warndlg('Nothing can be undone')
                app.setready
                return
            end
            temp=app.currentData;
            app.currentData=app.previousData;
            app.previousData=temp;
            if strcmp(app.UndoButton.Text,'Undo')
                disp('Undo one step')
                ind=find(app.tag=='_');
                ind=ind(end);
                app.UndoButton.Tooltip=app.tag(ind:end);
                app.tag(ind:end)=[];
                %                 app.UndoButton.Text='Redo';
                app.UndoButton.Text='Redo';
            else
                disp('Redo one step')
                app.tag=[app.tag,app.UndoButton.Tooltip];
                %                 app.UndoButton.Text='Undo';
                app.UndoButton.Text='Undo';
            end
            app.updatepreview;
            app.setready
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            disp('===== SaveButtonPushed =====')
            app.setbusy
            data=app.currentData;
            datatag=app.tag;
            ind=find(app.filename=='.');ind=ind(end);
            save([app.filename(1:ind-1),datatag,'.mat'],'data','datatag','-mat')
            disp(['Data successfully saved to ',app.filename(1:ind-1),datatag,'.mat'])
            app.setready
        end

        % Button pushed function: FillbadchannelsButton
        function FillbadchannelsButtonPushed(app, event)
            disp('===== FillBadChannelsButtonPushed =====')
            app.setbusy;
            app.previousData=app.currentData;
            app.currentData=RemoveOutliers(app.currentData);
            app.updatepreview
            app.UndoButton.Text='Undo';
            %             app.tag=[app.tag,'_OLRem'];
            app.setready;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            answer = questdlg('Do you really wish to exit? Unsaved results will be lost!', ...
               	'Comfirm?', ...
               	'Exit','Cancel','Exit');
            % Handle response
            if strcmp(answer,'Exit')
                diary off
                delete(app)
            end
            
            
        end

        % Callback function
        function OptionsButtonPushed(app, event)
            
            if strcmp(app.ShowoptionsMenu.Checked,'on')
                app.UIFigure.Position=app.UIFigure.Position.*[1 1 0 0]+[0 0 730 594];
                app.ShowoptionsMenu.Checked='off';
            else
                app.UIFigure.Position=app.UIFigure.Position.*[1 1 0 0]+[0 0 730 594]+[0 0 185 0];
                app.ShowoptionsMenu.Checked='on';
            end
            
        end

        % Callback function
        function RestoredefalutButtonPushed(app, event)
            app.WritelogfileCheckBox.Value=1;
            app.AlignmentsigmaEditField.Value=200;
            app.BinningsizeyxeqEditField_1.Value=1;
            app.BinningsizeyxeqEditField_2.Value=1;
            app.BinningsizeyxeqEditField_3.Value=2;
            app.BinningsizeyxeqEditField_4.Value=2;
            app.BM3DdenoisefactorEditField.Value=0.9;
            app.AlignmentprecisionEditField.Value=0.1;
            app.IterationEditField.Value=5;
        end

        % Menu selected function: ExporttoworkspaceMenu
        function Export2WSButtonPushed(app, event)
            prompt = {'Name:'};
            dlgtitle = 'Export data to workspace';
            dims = [1 35];
            definput = {'ExportedData'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if ~isempty(answer)
                assignin('base',answer{1},app.currentData)
            end
        end

        % Button pushed function: SliceButton
        function SliceButtonPushed(app, event)
            if app.SumoverDropDown.Value=='y'
                sumregion=strrep(app.sumregionEditField.Value,'end',num2str(app.currentData.ydim));
                sumdir=1;
            else
                sumregion=strrep(app.sumregionEditField.Value,'end',num2str(app.currentData.xdim));
                sumdir=2;
            end
            sumregion=str2num(sumregion);
            if strcmp(app.UsecolormapMenu.Checked,'on')
                SliceYQ(app.currentData,app.qsamplingEditField.Value,app.IntegrationwidthEditField.Value,sumdir,sumregion)
                if ~isempty(app.elim)
                    hline = findobj(gcf, 'type', 'axes');
                    set(hline,'ylim',app.elim)
                end
            else
                SliceYQLine(app.currentData,app.qsamplingEditField.Value,app.IntegrationwidthEditField.Value,sumdir,sumregion)
                if ~isempty(app.elim)
                    hline = findobj(gcf, 'type', 'axes');
                    set(hline(2:end),'xlim',app.elim)
                    ylim(hline(2:end),'auto')
                    set(hline(1),'ylim',app.elim)
                end
                set(findall(gcf,'-property','FontSize'),'FontSize',10)
                
            end
        end

        % Menu selected function: UpdatepreviewMenu
        function UpdatepreviewMenuSelected(app, event)
            app.updatepreview;
        end

        % Menu selected function: AlignmentMenu
        function AlignmentMenuSelected(app, event)
            prompt = {'Gaussian weight sigma:','Alignment precision:'};
            dlgtitle = 'Alignment parameters';
            dims = [1 35];
            definput = arrayfun(@num2str, str2num(app.AlignmentMenu.Tooltip), 'uni',0);
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            try
                answer=[answer{1},'  ',answer{2}];
                app.AlignmentMenu.Tooltip=answer;
            catch
            end
        end

        % Menu selected function: BinningsizeMenu
        function BinningsizeMenuSelected(app, event)
            prompt = {'x:','y:','e:','q:'};
            dlgtitle = 'Binning size';
            dims = [1 35];
            definput = arrayfun(@num2str, str2num(app.BinningsizeMenu.Tooltip), 'uni',0);
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            try
                answer=[answer{2},'  ',answer{1},'  ',answer{3},'  ',answer{4}];
                app.BinningsizeMenu.Tooltip=answer;
            catch
            end
        end

        % Menu selected function: DenoisefactorMenu
        function DenoisefactorMenuSelected(app, event)
            prompt = {'Denoise factor'};
            dlgtitle = 'Denoise factor';
            dims = [1 35];
            definput = {'0.9'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            try
                answer=answer{1};
                app.DenoisefactorMenu.Tooltip=answer;
            catch
            end
        end

        % Menu selected function: RestoredefaultparametersMenu
        function RestoredefaultparametersMenuSelected(app, event)
            app.AlignmentMenu.Tooltip='200  0.1';
            app.BinningsizeMenu.Tooltip='1  1  2  2';
            app.DenoisefactorMenu.Tooltip='0.9';
            app.WritelogfileMenu.Checked='off';
            app.EnforceBM3DMenu.Checked='on';
        end

        % Menu selected function: WritelogfileMenu
        function WritelogfileMenuSelected(app, event)
            if strcmp(app.WritelogfileMenu.Checked,'on')
                app.WritelogfileMenu.Checked='off';
            else
                app.WritelogfileMenu.Checked='on';
            end
        end

        % Menu selected function: EnforceBM3DMenu
        function EnforceBM3DMenuSelected(app, event)
            if strcmp(app.EnforceBM3DMenu.Checked,'on')
                app.EnforceBM3DMenu.Checked='off';
            else
                app.EnforceBM3DMenu.Checked='on';
            end
        end

        % Menu selected function: UsecolormapMenu
        function UsecolormapMenuSelected(app, event)
            
            app.UsecolormapMenu.Checked='on';
            app.UselineplotMenu.Checked='off';
        end

        % Menu selected function: UselineplotMenu
        function UselineplotMenuSelected(app, event)
            app.UsecolormapMenu.Checked='off';
            app.UselineplotMenu.Checked='on';
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            app.setbusy
            data=app.currentData;
            datatag=app.tag;
            ind=find(app.filename=='.');ind=ind(end);
            [file,path] = uiputfile([app.filename(1:ind-1),datatag,'.mat']);
            try
                save([path,file],'data','datatag','-mat')
                disp(['Data successfully saved to ',[path,file]])
            catch
            end
            app.setready
        end

        % Menu selected function: SetelimMenu
        function SetelimMenuSelected(app, event)
            prompt = {'Lower (meV):','Upper (meV):'};
            dlgtitle = 'Energy limit';
            dims = [1 35];
            definput = {'',''};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            try
                answer=[answer{1},'  ',answer{2}];
                app.SetelimMenu.Tooltip=answer;
                app.elim=str2num(answer);
            catch
            end
        end

        % Menu selected function: clockwiseMenu
        function clockwiseMenuSelected(app, event)
            disp('===== Rotate clockwise =====')
            app.setbusy;
            app.previousData=app.currentData;
            app.currentData=Rotate4DEELS(app.currentData,1);
            app.updatepreview;
            app.UndoButton.Text='Undo';
            app.setready;
        end

        % Menu selected function: counterclockwiseMenu
        function counterclockwiseMenuSelected(app, event)
            disp('===== Rotate counter clockwise =====')
            app.setbusy;
            app.previousData=app.currentData;
            app.currentData=Rotate4DEELS(app.currentData,2);
            app.updatepreview;
            app.UndoButton.Text='Undo';
            app.setready;
        end

        % Menu selected function: xMenu
        function xMenuSelected(app, event)
            disp('===== Flip x =====')
            app.setbusy;
            app.previousData=app.currentData;
            app.currentData=Flip4DEELS(app.currentData,2);
            app.updatepreview;
            app.UndoButton.Text='Undo';
            app.setready;
        end

        % Menu selected function: yMenu
        function yMenuSelected(app, event)
            disp('===== Flip y =====')
            app.setbusy;
            app.previousData=app.currentData;
            app.currentData=Flip4DEELS(app.currentData,1);
            app.updatepreview;
            app.UndoButton.Text='Undo';
            app.setready;
        end

        % Menu selected function: ImportfromworkspaceMenu
        function ImportfromworkspaceMenuSelected(app, event)
            vars = evalin('base', 'whos');
            cell_array = {};
            for i=1:length(vars)
                iseels=evalin('base', ['strcmp("EELS4D",class(',vars(i).name,'))']);
                if iseels
                    cell_array = [cell_array,vars(i).name];
                end
            end
            [index,tf]=listdlg('ListString',cell_array,'PromptString','Select data','SelectionMode','single','Name','Data Selection');
            if tf
                app.currentData=evalin('base',cell_array{index});
                app.updatepreview;
                if isempty(app.filename)
                    app.filename=[app.toolboxpath,'\fromworkspace.mat'];
                    warndlg('Data imported from workspace will be saved under TOOLBOX path. Use "save as" option, or change "Filepath" to avoid overwriting.')
                else
                    warndlg('Data imported from workspace will be saved with the same filename as you oladed earlier. Use "save as" option, or change "Filepath" to avoid overwriting.')
                end
                
            end
        end

        % Menu selected function: FilepathMenu
        function FilepathMenuSelected(app, event)
            prompt = {'File name:'};
            dlgtitle = 'Change path';
            dims = [1 35];
            definput = {app.filename};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if ~isempty(answer)
                app.filename=answer{1};
                app.FilepathMenu.Tooltip=answer{1};
            end
        end

        % Menu selected function: RefreshMenu
        function RefreshMenuSelected(app, event)

            temp=ls([app.toolboxpath,'\4DEELS\Custom\Process']);
            funlist={};
            for ii=1:size(temp,1)
                funname=temp(ii,:);
                funname=funname(~isspace(funname));
                if strcmp(funname(end),'m')
                    funlist=[funlist,funname];
                end
            end
            m = app.ProcessingfunctionsMenu;
            delete(m.Children)
            for ii=1:numel(funlist)
                mitem = uimenu(m,'Text',funlist{ii});
                mitem.MenuSelectedFcn = @helpfun1;
            end
            
            temp=ls([app.toolboxpath,'\4DEELS\Custom\Visualize']);
            funlist={};
            for ii=1:size(temp,1)
                funname=temp(ii,:);
                funname=funname(~isspace(funname));
                if strcmp(funname(end),'m')
                    funlist=[funlist,funname];
                end
            end
            m = app.VisualizingfunctionsMenu;
            delete(m.Children)
            for ii=1:numel(funlist)
                mitem = uimenu(m,'Text',funlist{ii});
                mitem.MenuSelectedFcn = @helpfun2;
            end
            
            temp=ls([app.toolboxpath,'\4DEELS\Custom\Executable']);
            funlist={};
            for ii=1:size(temp,1)
                funname=temp(ii,:);
                funname=funname(~isspace(funname));
                if strcmp(funname(end),'m')
                    funlist=[funlist,funname];
                end
            end
            m = app.ExecutableMenu;
            delete(m.Children)
            for ii=1:numel(funlist)
                mitem = uimenu(m,'Text',funlist{ii});
                mitem.MenuSelectedFcn = @helpfun3;
            end
            
            function helpfun1(src,~)
                app.setbusy
                fun=src.Text;
                fun=fun(1:end-2);
                app.currentData=eval([fun,'(app.currentData)']);
                disp(['app.currentData=',fun,'(app.currentData)'])
                app.setready
            end
            function helpfun2(src,~)
                app.setbusy
                fun=src.Text;
                fun=fun(1:end-2);
                eval([fun,'(app.currentData)'])
                disp([fun,'(app.currentData)'])
                app.setready
            end
            function helpfun3(src,~)
                app.setbusy
                eval(fileread(src.Text))
                app.setready
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '4D-EELS Toolbox V1.0 beta';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportMenu.Accelerator = 'I';
            app.ImportMenu.Text = 'Import';
            app.ClearMenu = uimenu(app.FileMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearMenu.Text = 'Clear';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Accelerator = 'S';
            app.SaveasMenu.Text = 'Save as';
            app.ImportfromworkspaceMenu = uimenu(app.FileMenu);
            app.ImportfromworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportfromworkspaceMenuSelected, true);
            app.ImportfromworkspaceMenu.Text = 'Import from workspace';
            app.ExporttoworkspaceMenu = uimenu(app.FileMenu);
            app.ExporttoworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @Export2WSButtonPushed, true);
            app.ExporttoworkspaceMenu.Accelerator = 'E';
            app.ExporttoworkspaceMenu.Text = 'Export to workspace';
            app.UndoredoMenu = uimenu(app.FileMenu);
            app.UndoredoMenu.MenuSelectedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoredoMenu.Accelerator = 'Z';
            app.UndoredoMenu.Text = 'Undo/redo';
            app.RotateMenu = uimenu(app.FileMenu);
            app.RotateMenu.Text = 'Rotate';
            app.clockwiseMenu = uimenu(app.RotateMenu);
            app.clockwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @clockwiseMenuSelected, true);
            app.clockwiseMenu.Text = '90 clockwise';
            app.counterclockwiseMenu = uimenu(app.RotateMenu);
            app.counterclockwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @counterclockwiseMenuSelected, true);
            app.counterclockwiseMenu.Text = '90 counter-clockwise';
            app.FlipMenu = uimenu(app.FileMenu);
            app.FlipMenu.Text = 'Flip';
            app.xMenu = uimenu(app.FlipMenu);
            app.xMenu.MenuSelectedFcn = createCallbackFcn(app, @xMenuSelected, true);
            app.xMenu.Text = 'x';
            app.yMenu = uimenu(app.FlipMenu);
            app.yMenu.MenuSelectedFcn = createCallbackFcn(app, @yMenuSelected, true);
            app.yMenu.Text = 'y';
            app.InfoMenu = uimenu(app.UIFigure);
            app.InfoMenu.Text = 'Info';
            app.DatasizeMenu = uimenu(app.InfoMenu);
            app.DatasizeMenu.Separator = 'on';
            app.DatasizeMenu.Text = 'Data size';
            app.PixelsizeMenu = uimenu(app.InfoMenu);
            app.PixelsizeMenu.Text = 'Pixel size';
            app.SpectralsamplingMenu = uimenu(app.InfoMenu);
            app.SpectralsamplingMenu.Text = 'Spectral sampling';
            app.FilepathMenu = uimenu(app.InfoMenu);
            app.FilepathMenu.MenuSelectedFcn = createCallbackFcn(app, @FilepathMenuSelected, true);
            app.FilepathMenu.Text = 'File path';
            app.AvailablememoryMenu = uimenu(app.InfoMenu);
            app.AvailablememoryMenu.Separator = 'on';
            app.AvailablememoryMenu.Text = 'Available memory';
            app.UsedmemoryMenu = uimenu(app.InfoMenu);
            app.UsedmemoryMenu.Text = 'Used memory';
            app.DisplayMenu = uimenu(app.UIFigure);
            app.DisplayMenu.Text = 'Display';
            app.SetelimMenu = uimenu(app.DisplayMenu);
            app.SetelimMenu.MenuSelectedFcn = createCallbackFcn(app, @SetelimMenuSelected, true);
            app.SetelimMenu.Text = 'Set elim';
            app.UpdatepreviewMenu = uimenu(app.DisplayMenu);
            app.UpdatepreviewMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdatepreviewMenuSelected, true);
            app.UpdatepreviewMenu.Separator = 'on';
            app.UpdatepreviewMenu.Text = 'Update preview';
            app.UsecolormapMenu = uimenu(app.DisplayMenu);
            app.UsecolormapMenu.MenuSelectedFcn = createCallbackFcn(app, @UsecolormapMenuSelected, true);
            app.UsecolormapMenu.Separator = 'on';
            app.UsecolormapMenu.Checked = 'on';
            app.UsecolormapMenu.Text = 'Use colormap';
            app.UselineplotMenu = uimenu(app.DisplayMenu);
            app.UselineplotMenu.MenuSelectedFcn = createCallbackFcn(app, @UselineplotMenuSelected, true);
            app.UselineplotMenu.Text = 'Use line plot';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.WritelogfileMenu = uimenu(app.OptionsMenu);
            app.WritelogfileMenu.MenuSelectedFcn = createCallbackFcn(app, @WritelogfileMenuSelected, true);
            app.WritelogfileMenu.Text = 'Write log file';
            app.AlignmentMenu = uimenu(app.OptionsMenu);
            app.AlignmentMenu.MenuSelectedFcn = createCallbackFcn(app, @AlignmentMenuSelected, true);
            app.AlignmentMenu.Tooltip = {'200  0.1'};
            app.AlignmentMenu.Text = 'Alignment';
            app.BinningsizeMenu = uimenu(app.OptionsMenu);
            app.BinningsizeMenu.MenuSelectedFcn = createCallbackFcn(app, @BinningsizeMenuSelected, true);
            app.BinningsizeMenu.Tooltip = {'1  1  2  2'};
            app.BinningsizeMenu.Text = 'Binning size';
            app.EnforceBM3DMenu = uimenu(app.OptionsMenu);
            app.EnforceBM3DMenu.MenuSelectedFcn = createCallbackFcn(app, @EnforceBM3DMenuSelected, true);
            app.EnforceBM3DMenu.Checked = 'on';
            app.EnforceBM3DMenu.Text = 'Enforce BM3D';
            app.DenoisefactorMenu = uimenu(app.OptionsMenu);
            app.DenoisefactorMenu.MenuSelectedFcn = createCallbackFcn(app, @DenoisefactorMenuSelected, true);
            app.DenoisefactorMenu.Tooltip = {'0.9'};
            app.DenoisefactorMenu.Text = 'Denoise factor';
            app.RestoredefaultparametersMenu = uimenu(app.OptionsMenu);
            app.RestoredefaultparametersMenu.MenuSelectedFcn = createCallbackFcn(app, @RestoredefaultparametersMenuSelected, true);
            app.RestoredefaultparametersMenu.Separator = 'on';
            app.RestoredefaultparametersMenu.Text = 'Restore default parameters';
            app.CustomMenu = uimenu(app.UIFigure);
            app.CustomMenu.Text = 'Custom';
            app.ProcessingfunctionsMenu = uimenu(app.CustomMenu);
            app.ProcessingfunctionsMenu.Text = 'Processing functions';
            app.VisualizingfunctionsMenu = uimenu(app.CustomMenu);
            app.VisualizingfunctionsMenu.Text = 'Visualizing functions';
            app.ExecutableMenu = uimenu(app.CustomMenu);
            app.ExecutableMenu.Text = 'Executable';
            app.RefreshMenu = uimenu(app.CustomMenu);
            app.RefreshMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshMenuSelected, true);
            app.RefreshMenu.Text = 'Refresh';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.UndoButton = uibutton(app.UIFigure, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.DatapreviewPanel = uipanel(app.UIFigure);
            app.DatapreviewPanel.Title = 'Data preview';
            app.UIAxes_2 = uiaxes(app.DatapreviewPanel);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, 'Position x')
            ylabel(app.UIAxes_2, 'Position y')
            app.UIAxes = uiaxes(app.DatapreviewPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Momentum')
            ylabel(app.UIAxes, 'Energy')
            app.brighter_upper = uibutton(app.DatapreviewPanel, 'push');
            app.brighter_upper.Text = '>>';
            app.dimmer_upper = uibutton(app.DatapreviewPanel, 'push');
            app.dimmer_upper.Text = '<<';
            app.brighter_lower = uibutton(app.DatapreviewPanel, 'push');
            app.brighter_lower.Text = '>>';
            app.dimmer_lower = uibutton(app.DatapreviewPanel, 'push');
            app.dimmer_lower.Text = '<<';
            app.DataprocessingPanel = uipanel(app.UIFigure);
            app.DataprocessingPanel.Tooltip = {''};
            app.DataprocessingPanel.Title = 'Data processing';
            app.AlignButton = uibutton(app.DataprocessingPanel, 'push');
            app.AlignButton.ButtonPushedFcn = createCallbackFcn(app, @AlignButtonPushed, true);
            app.AlignButton.Text = 'Align';
            app.RemovedarkcurrentButton = uibutton(app.DataprocessingPanel, 'push');
            app.RemovedarkcurrentButton.ButtonPushedFcn = createCallbackFcn(app, @RemovedarkcurrentButtonPushed, true);
            app.RemovedarkcurrentButton.Text = 'Remove dark current';
            app.CropButton = uibutton(app.DataprocessingPanel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.BM3DButton = uibutton(app.DataprocessingPanel, 'push');
            app.BM3DButton.ButtonPushedFcn = createCallbackFcn(app, @BM3DButtonPushed, true);
            app.BM3DButton.Text = 'BM3D';
            app.BinButton = uibutton(app.DataprocessingPanel, 'push');
            app.BinButton.ButtonPushedFcn = createCallbackFcn(app, @BinButtonPushed, true);
            app.BinButton.Text = 'Bin';
            app.FillbadchannelsButton = uibutton(app.DataprocessingPanel, 'push');
            app.FillbadchannelsButton.ButtonPushedFcn = createCallbackFcn(app, @FillbadchannelsButtonPushed, true);
            app.FillbadchannelsButton.Text = 'Fill bad channels';
            app.StatusReadyLabel = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.DevelopedbyRuishiQiLabel = uilabel(app.UIFigure);
            app.DevelopedbyRuishiQiLabel.Tooltip = {'Email: rs-qi@pku.edu.cn'};
            app.DevelopedbyRuishiQiLabel.Text = 'Developed by Ruishi Qi';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.DynamicalplotTab = uitab(app.TabGroup2);
            app.DynamicalplotTab.Title = 'Dynamical plot';
            app.STAcorrectionCheckBox = uicheckbox(app.DynamicalplotTab);
            app.STAcorrectionCheckBox.Text = 'STA correction';
            app.SelectXYButton = uibutton(app.DynamicalplotTab, 'push');
            app.SelectXYButton.ButtonPushedFcn = createCallbackFcn(app, @SelectXYButtonPushed, true);
            app.SelectXYButton.Text = 'Select XY';
            app.SelectQEButton = uibutton(app.DynamicalplotTab, 'push');
            app.SelectQEButton.ButtonPushedFcn = createCallbackFcn(app, @SelectQEButtonPushed, true);
            app.SelectQEButton.Text = 'Select QE';
            app.SelectXQButton = uibutton(app.DynamicalplotTab, 'push');
            app.SelectXQButton.ButtonPushedFcn = createCallbackFcn(app, @SelectXQButtonPushed, true);
            app.SelectXQButton.Text = 'Select XQ';
            app.SelectYQButton = uibutton(app.DynamicalplotTab, 'push');
            app.SelectYQButton.ButtonPushedFcn = createCallbackFcn(app, @SelectYQButtonPushed, true);
            app.SelectYQButton.Text = 'Select YQ';
            app.SliceTab = uitab(app.TabGroup2);
            app.SliceTab.Title = 'Slice';
            app.SliceButton = uibutton(app.SliceTab, 'push');
            app.SliceButton.ButtonPushedFcn = createCallbackFcn(app, @SliceButtonPushed, true);
            app.SliceButton.Text = 'Slice';
            app.SumoverDropDownLabel = uilabel(app.SliceTab);
            app.SumoverDropDownLabel.Text = 'Sum over';
            app.SumoverDropDown = uidropdown(app.SliceTab);
            app.SumoverDropDown.Items = {'x', 'y'};
            app.SumoverDropDown.Value = 'y';
            app.qsamplingEditFieldLabel = uilabel(app.SliceTab);
            app.qsamplingEditFieldLabel.Text = 'q-sampling';
            app.qsamplingEditField = uieditfield(app.SliceTab, 'numeric');
            app.qsamplingEditField.Limits = [1 Inf];
            app.qsamplingEditField.RoundFractionalValues = 'on';
            app.qsamplingEditField.Tooltip = {'# of slices between two Bragg spots.'};
            app.qsamplingEditField.Value = 8;
            app.IntegrationwidthEditFieldLabel = uilabel(app.SliceTab);
            app.IntegrationwidthEditFieldLabel.Tooltip = {'# of momentum channels you want to integrate over. Set to 0 to automatically calculate (no overlap). '};
            app.IntegrationwidthEditFieldLabel.Text = 'Integration width';
            app.IntegrationwidthEditField = uieditfield(app.SliceTab, 'numeric');
            app.Label_2 = uilabel(app.SliceTab);
            app.Label_2.Text = '=';
            app.sumregionEditField = uieditfield(app.SliceTab, 'text');
            app.sumregionEditField.Value = '1:end';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EELS4D_TOOLBOX

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end